classdef robot_arm_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartButton                    matlab.ui.control.Button
        PickingorderDropDown           matlab.ui.control.DropDown
        PickingorderDropDownLabel      matlab.ui.control.Label
        ForceBoundsButton              matlab.ui.control.Button
        liveXEditField                 matlab.ui.control.NumericEditField
        liveYEditField                 matlab.ui.control.NumericEditField
        liveZEditField                 matlab.ui.control.NumericEditField
        ZEditField_2Label              matlab.ui.control.Label
        YEditField_2Label              matlab.ui.control.Label
        XEditField_2Label              matlab.ui.control.Label
        GUIEndEffectorLabel            matlab.ui.control.Label
        roll2EditField_2               matlab.ui.control.NumericEditField
        roll2EditField_2Label          matlab.ui.control.Label
        roll1EditField_2               matlab.ui.control.NumericEditField
        roll1EditField_2Label          matlab.ui.control.Label
        yawEditField_2                 matlab.ui.control.NumericEditField
        yawEditField_2Label            matlab.ui.control.Label
        MotorAnglesLabel               matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        EndEffectorCoordinatesLabel_3  matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        ControltypeButtonGroup         matlab.ui.container.ButtonGroup
        AutomaticButton                matlab.ui.control.RadioButton
        OffButton                      matlab.ui.control.RadioButton
        CoordinatesButton              matlab.ui.control.RadioButton
        DialcontrolButton              matlab.ui.control.RadioButton
        GoButton                       matlab.ui.control.Button
        roll2KnobLabel                 matlab.ui.control.Label
        roll2Knob                      matlab.ui.control.Knob
        yawKnob                        matlab.ui.control.Knob
        yawKnobLabel                   matlab.ui.control.Label
        roll1Knob                      matlab.ui.control.Knob
        roll1KnobLabel                 matlab.ui.control.Label
        roll2EditField                 matlab.ui.control.NumericEditField
        roll1EditField                 matlab.ui.control.NumericEditField
        yawEditField                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        SimulinkModelName % Name of simulink model
        
        sentyaw % yaw sent to simulink model
        sentroll1 % roll1 --||--
        sentroll2 % roll2 --||--

        destX % destination X coordinate for IK control
        destY % destination Y coordinate for IK control
        destZ % destination Z coordinate for IK control

        liveYaw     % live yaw value;  'alpha' in workspace
        liveRoll1   % live roll1 value; 'beta' in workspace
        liveRoll2   % live roll2 value; 'gama' in workspace

        syncIK % sync IK or not
    end
    
    methods (Access = private)
        
        function setYaw(app, yaw)
            % round to 3 decimals
            yaw = round(yaw*1000)/1000;
            
            % recalculate angles to bounds
            if (yaw > 50)
                yaw = 50;
            end

            % sync knob and edit box
            app.yawKnob.Value = yaw;
            app.yawEditField.Value = yaw;

            % sync IK
            if (app.syncIK)
                app.yawEditField_2.Value = yaw;
                app.getDK(app.yawEditField_2.Value, app.roll1EditField_2.Value, app.roll2EditField_2.Value); app.updateIKCoords();
            end

            % recalculate angles to model bounds
            if (yaw >= -90) && (yaw <= 50)
                syaw = 180 - yaw; % yaw that will be sent to the simulink model
            end

            % send data to model
            % yaw_rad = round(yaw*pi/180*1000)/1000;
            % set_param([app.SimulinkModelName '/yaw'], 'Value', mat2str(yaw_rad));
            set_param([app.SimulinkModelName '/yaw'], 'Value', mat2str(syaw));
            app.sentyaw = syaw;

            %directKinematics(app, app.sentyaw, app.sentroll1, app.sentroll2);
        end

        function setRoll1(app, roll1)
            % round to 3 decimals
            roll1 = round(roll1*1000)/1000;
            
            % recalculate angles to bounds
            if (roll1 < 3)
                roll1 = 3;
            end

            % sync knob and edit box
            app.roll1Knob.Value = roll1;
            app.roll1EditField.Value = roll1;

            % sync IK
            if (app.syncIK)
                app.roll1EditField_2.Value = roll1;
                app.getDK(app.yawEditField_2.Value, app.roll1EditField_2.Value, app.roll2EditField_2.Value); app.updateIKCoords();
            end

            % recalculate angles to model bounds
            if (roll1 >= 0) && (roll1 <= 90)
                sroll1 = 360-roll1; % roll1 that will be sent to the simulink model
            end

            % send data to model
            % roll1_rad = round(roll1*pi/180*1000)/1000;
            % set_param([app.SimulinkModelName '/roll1'], 'Value', mat2str(roll1_rad));
            set_param([app.SimulinkModelName '/roll1'], 'Value', mat2str(sroll1));
            app.sentroll1 = sroll1;
            % directKinematics(app);
        end

        function setRoll2(app, roll2)
            % round to 3 decimals
            roll2 = round(roll2*1000)/1000;
            
            % recalculate angles to bounds
            if (roll2 < 5)
                roll2 = 5;
            end

            % sync knob and edit box
            app.roll2Knob.Value = roll2;
            app.roll2EditField.Value = roll2;

            % sync IK
            if (app.syncIK)
                app.roll2EditField_2.Value = roll2;
                app.getDK(app.yawEditField_2.Value, app.roll1EditField_2.Value, app.roll2EditField_2.Value); app.updateIKCoords();
            end
            
            % % recalculate angles to model bounds
            % if roll2 >= 0 && roll2 <= 150 roll2 = roll2
            sroll2 = roll2; % roll2 that will be sent to the simulink model

            % send data to model
            % roll2_rad = round(roll2*pi/180*1000)/1000;
            % set_param([app.SimulinkModelName '/roll2'], 'Value', mat2str(roll2_rad));
            set_param([app.SimulinkModelName '/roll2'], 'Value', mat2str(sroll2));
            app.sentroll2 = sroll2;
        end
        
        % IK boxes (rotation destinations)
        function setYawDest(app, yaw)
            app.yawEditField_2.Value = yaw;
        end

        function setRoll1Dest(app, roll1)
            app.roll1EditField_2.Value = roll1;
        end

        function setRoll2Dest(app, roll2)
            app.roll2EditField_2.Value = roll2;
        end

        % knobs control
        function controlKnobs(app, state)
            % yaw dial
            app.yawKnob.Enable = state;
            app.yawEditField.Enable = state;
            
            % roll1 dial
            app.roll1Knob.Enable = state;
            app.roll1EditField.Enable = state;
            
            % roll2 dial
            app.roll2Knob.Enable = state;
            app.roll2EditField.Enable = state;
            
            % labels
            app.yawKnobLabel.Enable = state;
            app.roll1KnobLabel.Enable = state;
            app.roll2KnobLabel.Enable = state;
        end

        % IK control
        function controlCoords(app, state)
            % XYZ fields
            app.XEditField.Enable = state;
            app.YEditField.Enable = state;
            app.ZEditField.Enable = state;
            
            % Go button
            app.GoButton.Enable = state;

            % Force Bounds button
            app.ForceBoundsButton.Enable = state;
            
            % yaw-roll1-roll2 fields
            app.yawEditField_2.Enable = state;
            app.roll1EditField_2.Enable = state;
            app.roll2EditField_2.Enable = state;

            % Labels
            app.EndEffectorCoordinatesLabel_3.Enable = state;
            app.XEditFieldLabel.Enable = state;
            app.YEditFieldLabel.Enable = state;
            app.ZEditFieldLabel.Enable = state;

            app.MotorAnglesLabel.Enable = state;
            app.yawEditField_2Label.Enable = state;
            app.roll1EditField_2Label.Enable = state;
            app.roll2EditField_2Label.Enable = state;
        end

        % Automatic control
        function controlAuto(app, state)
            % Start Button
            app.StartButton.Enable = state;

            % Dropdown menu
            app.PickingorderDropDown.Enable = state;
            app.PickingorderDropDownLabel.Enable = state;
        end

        % all controls off
        function Off(app)
            app.controlKnobs(false);
            app.controlCoords(false);
            app.controlAuto(false);
        end

        % DK evaluation ( angles -> coords )
        function getDK(app, yaw, roll1, roll2) % DK via unconverted motor angles; angles that haven't been constrained to the motor's bounds
            % yaw conversion
            %{
            if (yaw >= -90) && (yaw <= 50)
                yaw = 180 - yaw;
            end
            %}
            % roll1 conversion
            %{
            if (roll1 >= 0) && (roll1 <= 90)
                roll1 = 360-roll1;
            end
            %}
            % roll2 conversion
            % roll2 = roll2
            
            [x, y, z] = DK(yaw, roll1, roll2);
            app.destX = x;
            app.destY = y;
            app.destZ = z;
            app.updateIKCoords();
        end

        function updateIKCoords(app)
            app.XEditField.Value = app.destX;
            app.YEditField.Value = app.destY;
            app.ZEditField.Value = app.destZ;
        end

        function getLiveAngles(app)
            % gets angles from workspace variable
            app.liveYaw = evalin('base', 'alpha');
            app.liveRoll1 = evalin('base', 'beta');
            app.liveRoll2 = evalin('base', 'gama');
        end
        
        function updateLiveCoords(app, yaw, roll1, roll2)
            %app.liveXEditField.Value = app.destX;
            %app.liveYEditField.Value = app.destY;
            %app.liveZEditField.Value = app.destZ;
            
            % calculate coords
            [x, y, z] = DK(yaw, roll1, roll2);
            
            % update table of live coordinates
            app.liveXEditField.Value = x;
            app.liveYEditField.Value = y;
            app.liveZEditField.Value = z;
        end

        function getSentDK(app, syaw, sroll1, sroll2) % DK via values already sent to motors
            [x, y, z] = DK(syaw, sroll1, sroll2);
            
            app.XEditField.Value = x;
            app.YEditField.Value = y;
            app.ZEditField.Value = z;
        end
        
        % IK evaluation ( coords -> angles )
        function getIK(app)
            % Destination is (app.destX, app.destY, app.destZ)
            %disp("Coordinates: " + app.destX + ", " + app.destY + ", " + app.destZ);
            [yaw, roll1, roll2] = IK(app.destX, app.destY, app.destZ); % calculate angles
            %printf("Angles:\n    yaw: %.4f\n  roll1: %.4f\n  roll2: %.4f\n", yaw, roll1, roll2)
            % send angles to IK's boxes
            if ~(yaw == roll1 && roll1 == roll2 && roll2 == 0)
                app.setYawDest(yaw);
                app.setRoll1Dest(roll1);
                app.setRoll2Dest(roll2);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.SimulinkModelName = 'robot_arm_simulink';
            app.SimulinkModelName = 'simulation_three_motors';
            app.destX = 0; app.destY = 0; app.destZ = 0;
            app.syncIK = false;

            app.setYaw(0);
            app.setRoll1(0);
            app.setRoll2(0);
            app.ControltypeButtonGroup.SelectedObject = app.OffButton;
            app.ControltypeButtonGroupSelectionChanged(app.OffButton);
        end

        % Value changed function: yawKnob
        function yawKnobValueChanged(app, event)
            value = app.yawKnob.Value;
            app.setYaw(value);
        end

        % Value changed function: yawEditField
        function yawEditFieldValueChanged(app, event)
            value = app.yawEditField.Value; % edit field is normal
            app.setYaw(value);
        end

        % Value changed function: roll1Knob
        function roll1KnobValueChanged(app, event)
            value = app.roll1Knob.Value;
            app.setRoll1(value);
        end

        % Value changed function: roll1EditField
        function roll1EditFieldValueChanged(app, event)
            value = app.roll1EditField.Value;
            app.setRoll1(value);
        end

        % Value changed function: roll2Knob
        function roll2KnobValueChanged(app, event)
            value = app.roll2Knob.Value;
            app.setRoll2(value);
        end

        % Value changed function: roll2EditField
        function roll2EditFieldValueChanged(app, event)
            value = app.roll2EditField.Value;
            app.setRoll2(value);
        end

        % Value changing function: roll1Knob
        function roll1KnobValueChanging(app, event)
            changingValue = event.Value;
            if (changingValue == 0)
                changingValue = 1;
            end
            app.roll1EditField.Value = round(changingValue*1000)/1000;
        end

        % Value changing function: yawKnob
        function yawKnobValueChanging(app, event)
            changingValue = event.Value;
            if (changingValue > 50)
                changingValue = 50;
            end
            app.yawEditField.Value = round(changingValue*1000)/1000;
        end

        % Value changing function: roll2Knob
        function roll2KnobValueChanging(app, event)
            changingValue = event.Value;
            if (event.Value == 0)
                changingValue = 1;
            end
            app.roll2EditField.Value = round(changingValue*1000)/1000;
        end

        % Selection changed function: ControltypeButtonGroup
        function ControltypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControltypeButtonGroup.SelectedObject;
            switch (selectedButton)
                case app.OffButton % "Off"
                    app.Off();
                    
                case app.CoordinatesButton % "Coordinates"
                    app.controlCoords(true); % --
                    app.controlKnobs(false);
                    app.controlAuto(false);
                    
                case app.DialcontrolButton % "Dial control"
                    app.controlCoords(false);
                    app.controlKnobs(true); % --
                    app.controlAuto(false);

                case app.AutomaticButton % "Automatic"
                    app.controlCoords(false);
                    app.controlKnobs(false);
                    app.controlAuto(true); % --

                otherwise % Default
                    app.Off();
            end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.destX = value;
            app.getIK();
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.destY = value;
            app.getIK();
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            app.destZ = value;
            app.getIK();
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            % evaluate if rotations are within appropriate limits
            % if yes then set rotations
            yaw = app.yawEditField_2.Value;
            roll1 = app.roll1EditField_2.Value;
            roll2 = app.roll2EditField_2.Value;
            if ~((yaw < -90 || roll1 < 0 || roll2 < 0) || (yaw > 50 || roll1 > 90 || roll2 > 150))
                % set roll1 and roll2 to real lower bounds
                app.setYaw(yaw);
                app.setRoll1(roll1);
                app.setRoll2(roll2);
            else
                error_title = "Illegal rotation request";
                error_message = "Illegal rotations: " + newline;
                if (yaw > 50 || yaw < -90)
                    error_message = error_message + "     yaw (" + yaw + ", should be [-90, 50])" + newline;
                end
                if (roll1 < 0 || roll1 > 90)
                    error_message = error_message + "    roll1 (" + roll1 + ", should be [0, 90])" + newline;
                end
                if (roll2 < 0 || roll2 > 150)
                    error_message = error_message + "    roll2 (" + roll2 + ", should be [0, 150])" + newline;
                end
                uialert(app.UIFigure, error_message, error_title)
            end
        end

        % Value changed function: yawEditField_2
        function yawEditField_2ValueChanged(app, event)
            value = app.yawEditField_2.Value;
            app.getDK(value, app.roll1EditField_2.Value, app.roll2EditField_2.Value);
        end

        % Value changed function: roll1EditField_2
        function roll1EditField_2ValueChanged(app, event)
            value = app.roll1EditField_2.Value;
            app.getDK(app.yawEditField_2.Value, value, app.roll2EditField_2.Value);
        end

        % Value changed function: roll2EditField_2
        function roll2EditField_2ValueChanged(app, event)
            value = app.roll2EditField_2.Value;
            app.getDK(app.yawEditField_2.Value, app.roll1EditField_2.Value, value);
        end

        % Button pushed function: ForceBoundsButton
        function ForceBoundsButtonPushed(app, event)
            % Force IK yaw
            disp(app.yawEditField_2.Value)
            if app.yawEditField_2.Value < -90
                app.yawEditField_2.Value = -90;
            elseif app.yawEditField_2.Value > 50
                app.yawEditField_2.Value = 50;
            end
            
            % Force IK roll1
            if app.roll1EditField_2.Value < 0
                app.roll1EditField_2.Value = 0;
            elseif app.roll1EditField_2.Value > 90
                app.roll1EditField_2.Value = 90;
            end
            
            % Force IK roll2
            if app.roll2EditField_2.Value < 0
                app.roll2EditField_2.Value = 0;
            elseif app.roll2EditField_2.Value > 150
                app.roll2EditField_2.Value = 150;
            end
        end

        % Value changed function: PickingorderDropDown
        function PickingorderDropDownValueChanged(app, event)
            value = app.PickingorderDropDown.Value;
            
        end

        % Callback function
        function PickingorderDropDownClicked(app, event)
            item = event.InteractionInformation.Item;

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            order = app.PickingorderDropDown.Value;
            switch (order)
                case "Blue, green, yellow" % BGY, 1
                    Algoritam(1)
                    
                case "Blue, yellow, green" % BYG, 2
                    Algoritam(2)
                    
                case "Green, blue, yellow" % GBY, 3
                    Algoritam(3)
                
                case "Green, yellow, blue" % GYB, 4
                    Algoritam(4)
                
                case "Yellow, blue, green" % YBG, 5
                    Algoritam(5)
                
                case "Yellow, green, blue" % YGB, 6
                    Algoritam(6)
                
                otherwise % Default
                    disp("Default case: null")
            end
            disp(order)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.yawEditField = uieditfield(app.UIFigure, 'numeric');
            app.yawEditField.Limits = [-90 50];
            app.yawEditField.ValueChangedFcn = createCallbackFcn(app, @yawEditFieldValueChanged, true);
            app.yawEditField.Tooltip = {'Rotation of 1st motor. Updates via "yaw" knob, keyboard input, and "Go" button. Defaults to 0.'};
            app.roll1EditField = uieditfield(app.UIFigure, 'numeric');
            app.roll1EditField.Limits = [0 90];
            app.roll1EditField.ValueChangedFcn = createCallbackFcn(app, @roll1EditFieldValueChanged, true);
            app.roll1EditField.Tooltip = {'Rotation of 2nd motor. Updates via "roll1" knob, keyboard input, and "Go" button. Defaults to 3.'};
            app.roll2EditField = uieditfield(app.UIFigure, 'numeric');
            app.roll2EditField.Limits = [0 150];
            app.roll2EditField.ValueChangedFcn = createCallbackFcn(app, @roll2EditFieldValueChanged, true);
            app.roll2EditField.Tooltip = {'Rotation of 3rd motor. Updates via "roll2" knob, keyboard input, and "Go" button. Defaults to 5.'};
            app.roll1KnobLabel = uilabel(app.UIFigure);
            app.roll1KnobLabel.Tooltip = {'Adjust rotation of 2nd motor'};
            app.roll1KnobLabel.Text = 'roll1';
            app.roll1Knob = uiknob(app.UIFigure, 'continuous');
            app.roll1Knob.Limits = [0 90];
            app.roll1Knob.MajorTicks = [1 15 30 45 60 75 90];
            app.roll1Knob.MajorTickLabels = {'1°', '15°', '30°', '45°', '60°', '75°', '90°'};
            app.roll1Knob.ValueChangedFcn = createCallbackFcn(app, @roll1KnobValueChanged, true);
            app.roll1Knob.ValueChangingFcn = createCallbackFcn(app, @roll1KnobValueChanging, true);
            app.roll1Knob.MinorTicks = [5 10 20 25 35 40 50 55 65 70 80 85];
            app.roll1Knob.Tooltip = {''};
            app.roll1Knob.Value = 1;
            app.yawKnobLabel = uilabel(app.UIFigure);
            app.yawKnobLabel.Tooltip = {'Adjust base motor rotation'};
            app.yawKnobLabel.Text = 'yaw';
            app.yawKnob = uiknob(app.UIFigure, 'continuous');
            app.yawKnob.Limits = [-90 90];
            app.yawKnob.MajorTicks = [-90 -75 -60 -45 -30 -15 0 15 30 50];
            app.yawKnob.MajorTickLabels = {'-90°', '-75°', '-60°', '-45°', '-30°', '-15°', '0°', '15°', '30°', '50°'};
            app.yawKnob.ValueChangedFcn = createCallbackFcn(app, @yawKnobValueChanged, true);
            app.yawKnob.ValueChangingFcn = createCallbackFcn(app, @yawKnobValueChanging, true);
            app.yawKnob.MinorTicks = [-80 -70 -50 -45 -40 -20 -10 10 20 40 45];
            app.yawKnob.Tooltip = {''};
            app.roll2Knob = uiknob(app.UIFigure, 'continuous');
            app.roll2Knob.Limits = [0 150];
            app.roll2Knob.MajorTicks = [1 15 30 45 60 75 90 105 120 135 150];
            app.roll2Knob.MajorTickLabels = {'1°', '15°', '30°', '45°', '60°', '75°', '90°', '105°', '120°', '135°', '150°'};
            app.roll2Knob.ValueChangedFcn = createCallbackFcn(app, @roll2KnobValueChanged, true);
            app.roll2Knob.ValueChangingFcn = createCallbackFcn(app, @roll2KnobValueChanging, true);
            app.roll2Knob.MinorTicks = [10 20 40 50 70 80 100 110 130 140];
            app.roll2Knob.Tooltip = {''};
            app.roll2Knob.Value = 1;
            app.roll2KnobLabel = uilabel(app.UIFigure);
            app.roll2KnobLabel.Tooltip = {'Adjust rotation of 3rd motor'};
            app.roll2KnobLabel.Text = 'roll2';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.ControltypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ControltypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ControltypeButtonGroupSelectionChanged, true);
            app.ControltypeButtonGroup.Title = 'Control type';
            app.DialcontrolButton = uiradiobutton(app.ControltypeButtonGroup);
            app.DialcontrolButton.Text = 'Dial control';
            app.DialcontrolButton.Value = true;
            app.CoordinatesButton = uiradiobutton(app.ControltypeButtonGroup);
            app.CoordinatesButton.Text = 'Coordinates';
            app.OffButton = uiradiobutton(app.ControltypeButtonGroup);
            app.OffButton.Text = 'Off';
            app.AutomaticButton = uiradiobutton(app.ControltypeButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.EndEffectorCoordinatesLabel_3 = uilabel(app.UIFigure);
            app.EndEffectorCoordinatesLabel_3.Text = 'End Effector Coordinates';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.MotorAnglesLabel = uilabel(app.UIFigure);
            app.MotorAnglesLabel.Tooltip = {'Updates with IK from XYZ on the left. When modified the reverse is acomplished using DK.'};
            app.MotorAnglesLabel.Text = 'Motor Angles';
            app.yawEditField_2Label = uilabel(app.UIFigure);
            app.yawEditField_2Label.Text = 'yaw';
            app.yawEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.yawEditField_2.ValueChangedFcn = createCallbackFcn(app, @yawEditField_2ValueChanged, true);
            app.yawEditField_2.Tooltip = {''};
            app.roll1EditField_2Label = uilabel(app.UIFigure);
            app.roll1EditField_2Label.Text = 'roll1';
            app.roll1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.roll1EditField_2.ValueChangedFcn = createCallbackFcn(app, @roll1EditField_2ValueChanged, true);
            app.roll1EditField_2.Tooltip = {''};
            app.roll2EditField_2Label = uilabel(app.UIFigure);
            app.roll2EditField_2Label.Text = 'roll2';
            app.roll2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.roll2EditField_2.ValueChangedFcn = createCallbackFcn(app, @roll2EditField_2ValueChanged, true);
            app.roll2EditField_2.Tooltip = {''};
            app.GUIEndEffectorLabel = uilabel(app.UIFigure);
            app.GUIEndEffectorLabel.Text = 'Computed GUI End Effector Coordinates';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.liveZEditField = uieditfield(app.UIFigure, 'numeric');
            app.liveZEditField.Editable = 'off';
            app.liveZEditField.Tooltip = {'Current Z coordinate for end effector.'};
            app.liveYEditField = uieditfield(app.UIFigure, 'numeric');
            app.liveYEditField.Editable = 'off';
            app.liveYEditField.Tooltip = {'Current Y coordinate for end effector.'};
            app.liveXEditField = uieditfield(app.UIFigure, 'numeric');
            app.liveXEditField.Editable = 'off';
            app.liveXEditField.Tooltip = {'Current X coordinate for end effector.'};
            app.ForceBoundsButton = uibutton(app.UIFigure, 'push');
            app.ForceBoundsButton.ButtonPushedFcn = createCallbackFcn(app, @ForceBoundsButtonPushed, true);
            app.ForceBoundsButton.Tooltip = {'Sets motor angles to nearest bounds if they are exceeded.'};
            app.ForceBoundsButton.Text = 'Force Bounds';
            app.PickingorderDropDownLabel = uilabel(app.UIFigure);
            app.PickingorderDropDownLabel.Text = {'Picking order'; ''};
            app.PickingorderDropDown = uidropdown(app.UIFigure);
            app.PickingorderDropDown.Items = {'Blue, green, yellow', 'Blue, yellow, green', 'Green, blue, yellow', 'Green, yellow, blue', 'Yellow, blue, green', 'Yellow, green, blue'};
            app.PickingorderDropDown.ValueChangedFcn = createCallbackFcn(app, @PickingorderDropDownValueChanged, true);
            app.PickingorderDropDown.Value = 'Blue, green, yellow';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'Start algorithm for picking up bricks'};
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_arm_gui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end