classdef simulink_callback_test_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        currentValue                matlab.ui.control.NumericEditField
        CurrentvalueEditFieldLabel  matlab.ui.control.Label
        inputValue                  matlab.ui.control.NumericEditField
        InputvalueEditFieldLabel    matlab.ui.control.Label
        sendValueButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        SimulinkModelName % simulink model name
    end
    
    methods (Access = public)
        
        function getResult(app)
            %app.currentValue.Value = char2mat(get_param(app.SimulinkModelName, "/output"));
            disp("getResult(app):")
            app.currentValue.Value = evalin('base', 'out.simout.Data');
            disp(app.currentValue.Value)
        end

        function updateGUI(app, varargin)
             % Create an object that gets the run-time value of the specified block
             rto = get_param([simulink_callback_test, '/output'], 'RuntimeObject');
             
             % Update the GUI accordingly. In this case a the value of an EditField is being updated.
             app.currentValue.Value = rto.InputPort(1).Data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.inputValue.Value = 0;
            app.currentValue.Value = 0;

            app.SimulinkModelName = "simulink_callback_test";
        end

        % Button pushed function: sendValueButton
        function sendValueButtonPushed(app, event)
            set_param(app.SimulinkModelName + '/input', 'Value', mat2str(app.inputValue.Value));
            disp("Button pushed.")
            set_param(app.SimulinkModelName, 'SimulationCommand', 'Start');
            pause(2)
            disp("Waited for two.")
            app.getResult()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sendValueButton = uibutton(app.UIFigure, 'push');
            app.sendValueButton.ButtonPushedFcn = createCallbackFcn(app, @sendValueButtonPushed, true);
            app.sendValueButton.Text = 'Send input & start';
            app.InputvalueEditFieldLabel = uilabel(app.UIFigure);
            app.InputvalueEditFieldLabel.Text = 'Input value';
            app.inputValue = uieditfield(app.UIFigure, 'numeric');
            app.CurrentvalueEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentvalueEditFieldLabel.Text = 'Current value';
            app.currentValue = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulink_callback_test_gui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end